classdef MatlabMidterm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EditField       matlab.ui.control.NumericEditField
        EditFieldLabel  matlab.ui.control.Label
        Button_4        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        CloseButton     matlab.ui.control.Button
        STOPButton      matlab.ui.control.Button
        GridAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end
    
    properties (Access = private)
        coordinates % Description
        direction % Description
        bullets
        run = 1
    end
    
    methods (Access = private)

        
        function jetPoints = jetCreate(~,pos,dir) 
            switch dir
                case 1
                    jetPoints = [pos(1) pos(2); pos(1) pos(2)+1; pos(1)-1 pos(2)-1; pos(1) pos(2)-1; pos(1)+1 pos(2)-1];
                case 3
                    jetPoints = [pos(1) pos(2); pos(1) pos(2)-1; pos(1)+1 pos(2)+1; pos(1) pos(2)+1; pos(1)-1 pos(2)+1];
                case 4
                    jetPoints = [pos(1) pos(2); pos(1)-1 pos(2); pos(1)+1 pos(2)-1; pos(1)+1 pos(2); pos(1)+1 pos(2)+1];
                case 2  
                    jetPoints = [pos(1) pos(2); pos(1)+1 pos(2); pos(1)-1 pos(2)-1; pos(1)-1 pos(2); pos(1)-1 pos(2)+1];
                otherwise

            end

        end
        
        function posUpdate(app)
            cla(app.GridAxes);
            hold(app.GridAxes, "on");

            for i = 1:2
                if app.coordinates(i,1) < 0
                    app.coordinates(i,1) = 54.5;
                end
                if app.coordinates(i,1) > 55
                    app.coordinates(i,1) = 0.5;
                end
                if app.coordinates(i,2) < 0
                    app.coordinates(i,2) = 44.5;
                end
                if app.coordinates(i,2) > 45
                    app.coordinates(i,2) = 0.5;
                end
            end


            jet1 = jetCreate(app,app.coordinates(1,:),app.direction(1));
            jet2 = jetCreate(app,app.coordinates(2,:),app.direction(2));
            
            plot(app.GridAxes,jet1(:,1),jet1(:,2),"square",MarkerFaceColor="Black",MarkerSize=16,MarkerEdgeColor="Black");
            plot(app.GridAxes,jet2(:,1),jet2(:,2),"square",MarkerFaceColor="Green",MarkerSize=16,MarkerEdgeColor="Green");
            bulletCount = height(app.bullets);
            for i = 1:bulletCount
                plot(app.GridAxes,app.bullets(i,2),app.bullets(i,3),"square",MarkerFaceColor="Red",MarkerSize=16,MarkerEdgeColor="Red");
            end
            
            hold(app.GridAxes, "off");
            
        end
        
        function jetForward(app,jetNumber)
                    switch app.direction(jetNumber)
                        case 1
                            app.coordinates(jetNumber,2) = app.coordinates(jetNumber,2) + 1;
                        case 3
                            app.coordinates(jetNumber,2) = app.coordinates(jetNumber,2) - 1;
                        case 2
                            app.coordinates(jetNumber,1) = app.coordinates(jetNumber,1) + 1;
                        case 4
                            app.coordinates(jetNumber,1) = app.coordinates(jetNumber,1) - 1;
                    end
        end
        
        function fire(app,player)
            bulletDirection = app.direction(player);
            switch bulletDirection
                case 1
                    bulletCoordinates = [app.coordinates(player,1) app.coordinates(player,2)+2];
                case 2
                    bulletCoordinates = [app.coordinates(player,1)+2 app.coordinates(player,2)];
                case 3
                    bulletCoordinates = [app.coordinates(player,1) app.coordinates(player,2)-2];
                case 4
                    bulletCoordinates = [app.coordinates(player,1)-2 app.coordinates(player,2)];
                otherwise
            end
            app.bullets = [app.bullets; player bulletCoordinates(1) bulletCoordinates(2) bulletDirection];
        end
        
        function game(app)
            while app.run
                bulletCount = height(app.bullets);
                for i = 1:bulletCount
                    switch app.bullets(i,4)
                        case 1
                            app.bullets(i,3) = app.bullets (i,3)+1;
                        case 2
                            app.bullets(i,2) = app.bullets (i,2)+1;
                        case 3
                            app.bullets(i,3) = app.bullets (i,3)-1;
                        case 4
                            app.bullets(i,2) = app.bullets (i,2)-1;
                    end
                end

                i = 1;
                while i <= height(app.bullets)
                    if app.bullets(i,2) <  0 || app.bullets(i,2) > 55
                        app.bullets(i,:) = [];
                        i = i-1;
                    else
                        if app.bullets(i,3) <  0 || app.bullets(i,3) > 45
                        app.bullets(i,:) = [];
                        i = i-1;
                        end
                    end
                    i = i+1;
                end

                posUpdate(app);
                pause(0.05);
            end
        end
    end
  
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.coordinates = [2.5 2.5; 52.5 42.5];
            app.direction = [1 3];
            posUpdate(app);
            game(app);
        end

        % Key press function: UIFigure
        function KeyPressFcn(app, event)
            key = event.Key;
            switch key
                case "uparrow"
                    jetForward(app,2);
                case "w"
                    jetForward(app,1);
                case "d"
                    app.direction(1) = app.direction(1) + 1;
                case "rightarrow"
                    app.direction(2) = app.direction(2) + 1;
                case "a"
                    app.direction(1) = app.direction(1) - 1;
                case "leftarrow"
                    app.direction(2) = app.direction(2) - 1;  
                case "v"
                    fire(app,1);
                case "m"
                    fire(app,2);
                otherwise
            end
            for i = 1:2
            if app.direction(i) == 0
                app.direction(i) = 4;
            end
            if app.direction(i) == 5
                app.direction(i) = 1;
            end
            end
            
            posUpdate(app);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.run = 0;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.GridAxes = uiaxes(app.UIFigure);
            zlabel(app.GridAxes, 'Z')
            app.GridAxes.XLim = [0 55];
            app.GridAxes.YLim = [0 45];
            app.GridAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54];
            app.GridAxes.XTickLabel = '';
            app.GridAxes.YTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44];
            app.GridAxes.YTickLabel = '';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabMidterm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end